classdef GoFerret_Squid < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GoFerretLabel                matlab.ui.control.Label
        FerretListBoxLabel           matlab.ui.control.Label
        FerretListBox                matlab.ui.control.ListBox
        Motu_Device_Label            matlab.ui.control.Label
        AudioOutputLabel             matlab.ui.control.Label
        Motu_Chan_CountLabel         matlab.ui.control.Label
        Motu_Sample_Rate_Label       matlab.ui.control.Label
        ExitButton                   matlab.ui.control.Button
        TestSpeakerButton            matlab.ui.control.Button
        SpeakerDropDownLabel         matlab.ui.control.Label
        SpeakerDropDown              matlab.ui.control.DropDown
        AttenuationdBEditFieldLabel  matlab.ui.control.Label
        Test_Speaker_Atten           matlab.ui.control.NumericEditField
        DurationsEditFieldLabel      matlab.ui.control.Label
        Test_Speaker_Duration        matlab.ui.control.NumericEditField
        TestAllSpeakersButton        matlab.ui.control.Button
        Test_All_Speaker_Atten       matlab.ui.control.NumericEditField
        Test_All_Speaker_Duration    matlab.ui.control.NumericEditField
        Motu_Sample_Rate_Label_2     matlab.ui.control.Label
        Simultaneous_Test            matlab.ui.control.Button
        Motu_Sample_Rate_Label_3     matlab.ui.control.Label
        Simultaneous_Test_Duration   matlab.ui.control.NumericEditField
        Simultaneous_Test_Atten      matlab.ui.control.NumericEditField
        WeightgEditFieldLabel        matlab.ui.control.Label
        WeightEditField              matlab.ui.control.NumericEditField
        Weight_Directory             matlab.ui.control.Label
        SquidEditionLabel            matlab.ui.control.Label
        StartButton                  matlab.ui.control.Button
        TaskListBoxLabel             matlab.ui.control.Label
        TaskListBox                  matlab.ui.control.ListBox
        ParametersListBoxLabel       matlab.ui.control.Label
        ParametersListBox            matlab.ui.control.ListBox
        Session_Time                 matlab.ui.control.Label
        LevelsListBoxLabel           matlab.ui.control.Label
        LevelsListBox                matlab.ui.control.ListBox
        TabGroup                     matlab.ui.container.TabGroup
        SquidLayoutTab               matlab.ui.container.Tab
        Grid_Layout                  matlab.ui.control.UIAxes
        AudioConnectionsTab          matlab.ui.container.Tab
        Audio_Layout                 matlab.ui.control.UIAxes
        CurrentParametersTab         matlab.ui.container.Tab
        parameter_table              matlab.ui.control.Table
        StimuliTab                   matlab.ui.container.Tab
        chan_sequence                matlab.ui.control.UIAxes
        current_status               matlab.ui.control.Label
        Motu_Buffer_Size_Label       matlab.ui.control.Label
        StopButton                   matlab.ui.control.Button
        PauseButton                  matlab.ui.control.Button
        current_block                matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Define global variables
            global motu gf
            
            % Specify path structure
            gf.defaultPaths = path;
            gf.dirs = struct('metadata','F:\Metadata',...
                            'squid_layout','C:\Users\Squid\Documents\Speaker_grid_layout.csv',...
                            'weight','F:\Dropbox\Ferret_weights\Online',...
                            'tasks','C:\Users\Squid\Documents\MATLAB\Applications\GoFerret_Squid',...
                            'stimData','F:\Stimulus_Data');            
            
            % Connect to motu and report status to user
            motu = audioDeviceWriter('Device','MOTU Pro Audio','Driver','ASIO');
            motu_info = info(motu);
            
            app.Motu_Device_Label.Text = motu.Device;
            app.Motu_Chan_CountLabel.Text = sprintf('%02d Channels', motu_info.MaximumOutputChannels);
            app.Motu_Sample_Rate_Label.Text = sprintf('Sample Rate: %02d Hz', motu.SampleRate);
            app.Motu_Buffer_Size_Label.Text = sprintf('Buffer: %02d Samples', motu.BufferSize);
            
            % List ferrets available for this app            
            ferrets = dir( fullfile( gf.dirs.metadata, 'F*'));
            ferrets = struct2table(ferrets);
            app.FerretListBox.Items = ferrets.name;
            
            % List ferrets available for this app            
            tasks = dir(gf.dirs.tasks);
            tasks = struct2table(tasks);
            tasks = tasks.name(tasks.isdir);    % Only care about folders
            app.TaskListBox.Items = tasks(3:end);   % Ignore . and ..
            TaskListBoxValueChanged(app, [])    % Update task structure
            
            % Update app labels for paths
            app.Weight_Directory.Text = gf.dirs.weight;
            
            % List speakers available for testing
            app.SpeakerDropDown.Items = cellfun(@num2str, num2cell(1 : motu_info.MaximumOutputChannels),'un', 0);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Show speaker grid
            
            % Start by setting axes properties
            set([app.Grid_Layout app.Audio_Layout],'xlim',[0 310],'ylim',[0 210],'ydir','reverse','FontSize',6,'nextplot','add');
            
            % Marker speaker ring within grid
            ring_int = 360 / 12;                                % Angle interval between speakers = 360° / number of speakers in ring
            ring_angles = (0 : ring_int : 360) + (ring_int/2);  % Angle of each speaker
            rho = norm([132.5, 12.5] - [155, 105]);                 % Speaker distance (in grid units) using reference position
            
            [ring_x, ring_y] = pol2cart( deg2rad(ring_angles), rho);    % Convert to cartesian coordinates
            
            for i = 1 : numel(ring_x)
                plot(app.Grid_Layout,  [0 ring_x(i)] + 155, [0 ring_y(i)] + 105,'--','color',[1 1 1]/2)    % Plot position
                plot(app.Audio_Layout, [0 ring_x(i)] + 155, [0 ring_y(i)] + 105,'--','color',[1 1 1]/2)    % Plot position
            end
            
            % Import speaker position data
            squid = importdata( gf.dirs.squid_layout);  % Layout path defined at start of this function
            gf.grid = array2table(squid.data,'VariableNames',squid.colheaders);
            gf.grid = sortrows(gf.grid,{'Motu_Device','Motu_Channel'});
            
            % Add label for matlab channel
            gf.grid.matlab_chan = transpose(1 : size(gf.grid,1));
            
            % Plot speaker positions
            text_color = {'b','r'};
            
            for i = 1 : size(gf.grid,1)   % for each speaker
             
                % Create text object stating the matlab channel of the speaker 
                h(i) = text( app.Grid_Layout, gf.grid.Grid_x_cm(i), gf.grid.Grid_y_cm(i), num2str(i),...
                            'UserData', gf.grid(i,:),'color',text_color{gf.grid.Channel(i)},'HorizontalAlignment','center');
                        
                % Create text showing the amplifier, zone and channel of each speaker, colored by the motu device used
                text(app.Audio_Layout, gf.grid.Grid_x_cm(i), gf.grid.Grid_y_cm(i),...
                        sprintf('R%d-Z%d-C%d\n(M%d-C%d)', gf.grid.Amp(i), gf.grid.Zone(i), gf.grid.Channel(i), gf.grid.Motu_Device(i), gf.grid.Motu_Channel(i)),...
                        'color',text_color{gf.grid.Channel(i)},'HorizontalAlignment','center','FontSize',8);
            end
            
            % Append handles to axis user data
            set(app.Grid_Layout,'UserData', h);
             
            % Mark midpoint of grid
            plotXLine(105, [app.Grid_Layout app.Audio_Layout]);   
            plotYLine(155, [app.Grid_Layout app.Audio_Layout]);


            %             % Markers define amplifiers (1-3)
            %             set(h(squid.Amp == 1),'Marker','o');
            %             set(h(squid.Amp == 2),'Marker','^');
            %             set(h(squid.Amp == 3),'Marker','s');
            
            % Use color to show motu and zone
            %             cvector = 0.3 : 0.1 : 1;
            %
            %             for i = 1 : 2 % For left and right channels
            %
            %                 % Define colormap
            %                 cmap = zeros(8,3);
            %                 if i > 1, cmap(:,1) = cvector; else, cmap(:,3) = cvector; end
            %
            %                 for j = 1 : 8 % For zones 1-8
            %
            %                     idx = squid.Motu_Device == i & squid.Zone == j;
            %                     set(h(idx),'MarkerEdgeColor',cmap(j,:),'MarkerFaceColor',cmap(end,:));
            %                 end
            %             end
            
            % Initialize GUI userdata parameters
            app.StopButton.UserData = 0;
            
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            
            % Release audio device
            global motu gf
            release(motu)
            delete(motu)
                        
            % Restore default paths
            path(gf.defaultPaths)
            
            % Clear globals
            clear global motu gf                        
            
            % Close application
            close(app.UIFigure)
            
        end

        % Button pushed function: TestSpeakerButton
        function TestSpeakerButtonPushed(app, event)
            
            % Get information from motu
            global motu
            motu_info = info(motu);
            
            % Define signal length
            nSamps = app.Test_Speaker_Duration.Value * motu.SampleRate;
            
            % Preassign audio background as silence on all channels
            audio_in = zeros(nSamps, motu_info.MaximumOutputChannels);
            
            % Add random noise to channel of interest
            atten = app.Test_Speaker_Atten.Value;
            chan = str2num( app.SpeakerDropDown.Value);
            audio_in(:,chan) = rand(nSamps,1) .* 10^(-(atten/20));
            
            % Highlight current speaker on grid map
            h = app.Grid_Layout.UserData;
            delete(findobj(app.Grid_Layout,'MarkerEdgeColor','y'))
            h_position = get(h(chan),'position');
            
            yh = plot(app.Grid_Layout, h_position(1), h_position(2),'o','MarkerEdgeColor','y',...
                'MarkerFaceColor','none','LineWidth',1.5,'MarkerSize',10);
            drawnow
            
            % Play in chunks according to buffer size of device
            play_in_chunks(motu, audio_in);
            
        end

        % Button pushed function: TestAllSpeakersButton
        function TestAllSpeakersButtonPushed(app, event)
            
            % Remember settings for single speakers
            single_speaker.atten = app.Test_Speaker_Atten.Value;
            single_speaker.duration = app.Test_Speaker_Duration.Value;
            
            % Apply settings for test of all speakers to single speaker test settings
            app.Test_Speaker_Atten.Value = app.Test_All_Speaker_Atten.Value;
            app.Test_Speaker_Duration.Value = app.Test_All_Speaker_Duration.Value;
            
            % Get number of speakers
            nSpeakers = numel(app.SpeakerDropDown.Items);
            
            % For each speaker
            for i = 1 : nSpeakers
                
                % Select speaker
                app.SpeakerDropDown.Value = num2str(i);
                
                % Play single speaker test
                TestSpeakerButtonPushed(app, event)
            end
            
            % Restore settings for single speakers
            app.Test_Speaker_Atten.Value = single_speaker.atten;
            app.Test_Speaker_Duration.Value = single_speaker.duration;
            
        end

        % Button pushed function: Simultaneous_Test
        function Simultaneous_TestButtonPushed(app, event)
            
            
            % Get information from motu
            global motu
            motu_info = info(motu);
            
            % Define signal length
            nSamps = app.Simultaneous_Test_Duration.Value * motu.SampleRate;
            
            % Create audio as noise on all channels
            audio_in = rand(nSamps, motu_info.MaximumOutputChannels);
            
            % Attenuate signal
            atten = app.Simultaneous_Test_Atten.Value;
            audio_in = audio_in .* 10^(-(atten/20));
            
            % Play in chunks according to buffer size of device
            play_in_chunks(motu, audio_in)
            
        end

        % Value changed function: WeightEditField
        function WeightEditFieldValueChanged(app, event)
            
            % Add weight to user data with time stamp
            app.WeightEditField.UserData = [now app.WeightEditField.Value];
            
            % Remove lock on startup function
            %             set(handles.startH,'enable','on')
            
            %             %%%% This code needs to be executed on run startup (which we haven't created yet)
            %             weightData = app.WeightEditField.UserData;
            %
            %             if ~isnan(weightData)   % If afternoon flag not set
            %
            %                 % Load past weight data for this subject
            %                 pathName = app.Weight_Directory.Text;
            %                 fileName = [app.FerretListBox.Value '.mat'];
            %
            %                 load(fullfile( pathName, fileName), 'T')
            %
            %                 % Convert to table for concatenation
            %                 weightData = array2table(weightData,'variableNames',{'DateNum','Weight'});
            %                 T = [T; weightData];
            %
            %                 % Save updated weight
            %                 save(fullfile( pathName, fileName), 'T')
            %
            %                 % Report minimum water and weight values
            %                 if strcmp('Mon',datestr(now,'ddd'))
            %                     figure('name',sprintf('%s: %dg %.0fg %.1fml', ferret, weightData.Weight, weightData.Weight*0.88, weightData.Weight*.06))
            %                 end
            %             end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            
            % Reset stop procedure
            app.StopButton.UserData = false;                       
            app.StopButton.BackgroundColor = 'w';
            app.StopButton.FontColor = [0.85 0.08 0.16];
            
            % Run main function
            eval( sprintf('%s(app)', strrep(app.LevelsListBox.Value,'.m','')))                                 
        end

        % Value changed function: TaskListBox
        function TaskListBoxValueChanged(app, event)
            value = app.TaskListBox.Value;
            
            % Update file path structure
            global gf
            gf.dirs.current_task = fullfile(gf.dirs.tasks, value);
            
            % List levels files
            levels = dir( fullfile(gf.dirs.current_task, 'stages\*.m'));
            levels = struct2table(levels);
            app.LevelsListBox.Items = levels.name;
            
            % Add task toolbox to path (this might be a bit slow)
            path(gf.defaultPaths)
            addpath( genpath( gf.dirs.current_task))
            
            % Update parameter list box
            LevelsListBoxValueChanged(app, event)
        end

        % Value changed function: LevelsListBox
        function LevelsListBoxValueChanged(app, event)
            
            global gf
            value = app.LevelsListBox.Value;
                                    
            % List parameter files for this level
            file_stub = strrep(value,'_Squid.m','*.txt'); % Look for any file name with prefix e.g. level01********.txt
            param_files = dir( fullfile(gf.dirs.current_task, 'parameters', file_stub));
            param_files = struct2table(param_files);
            app.ParametersListBox.Items = param_files.name;         
            
            % Load initial parameters
            ParametersListBoxValueChanged(app, event)
        end

        % Value changed function: ParametersListBox
        function ParametersListBoxValueChanged(app, event)
            
            global gf
            value = app.ParametersListBox.Value;
            
            % Load parameters file
            gf.dirs.paramFile = fullfile(gf.dirs.current_task, 'parameters', value);            
            [a, b] = textread(gf.dirs.paramFile, '%s %s'); 
            
            % Put in table in tab
            set(app.parameter_table,'Data',[a b]); 
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            
            % Update user data
            app.StopButton.UserData = true;
            
            % Change color of button for feedback
            app.StopButton.BackgroundColor = 'r';
            app.StopButton.FontColor = 'w';
            
            app.current_status.Text = 'Stopped...';
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GoFerretLabel = uilabel(app.UIFigure);
            app.GoFerretLabel.Text = 'GoFerret';
            app.FerretListBoxLabel = uilabel(app.UIFigure);
            app.FerretListBoxLabel.Text = 'Ferret';
            app.FerretListBox = uilistbox(app.UIFigure);
            app.Motu_Device_Label = uilabel(app.UIFigure);
            app.Motu_Device_Label.Text = 'Motu device name';
            app.AudioOutputLabel = uilabel(app.UIFigure);
            app.AudioOutputLabel.Text = 'Audio Output';
            app.Motu_Chan_CountLabel = uilabel(app.UIFigure);
            app.Motu_Chan_CountLabel.Text = 'Motu channel count';
            app.Motu_Sample_Rate_Label = uilabel(app.UIFigure);
            app.Motu_Sample_Rate_Label.Text = 'Motu sample rate';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.TestSpeakerButton = uibutton(app.UIFigure, 'push');
            app.TestSpeakerButton.ButtonPushedFcn = createCallbackFcn(app, @TestSpeakerButtonPushed, true);
            app.TestSpeakerButton.Text = 'Test Speaker';
            app.SpeakerDropDownLabel = uilabel(app.UIFigure);
            app.SpeakerDropDownLabel.Text = 'Speaker';
            app.SpeakerDropDown = uidropdown(app.UIFigure);
            app.SpeakerDropDown.Items = {'1', '2', '3', '4'};
            app.SpeakerDropDown.Value = '1';
            app.AttenuationdBEditFieldLabel = uilabel(app.UIFigure);
            app.AttenuationdBEditFieldLabel.Text = 'Attenuation (dB)';
            app.Test_Speaker_Atten = uieditfield(app.UIFigure, 'numeric');
            app.Test_Speaker_Atten.Value = 45;
            app.DurationsEditFieldLabel = uilabel(app.UIFigure);
            app.DurationsEditFieldLabel.Text = 'Duration (s)';
            app.Test_Speaker_Duration = uieditfield(app.UIFigure, 'numeric');
            app.Test_Speaker_Duration.Value = 1;
            app.TestAllSpeakersButton = uibutton(app.UIFigure, 'push');
            app.TestAllSpeakersButton.ButtonPushedFcn = createCallbackFcn(app, @TestAllSpeakersButtonPushed, true);
            app.TestAllSpeakersButton.Text = 'Test All Speakers';
            app.Test_All_Speaker_Atten = uieditfield(app.UIFigure, 'numeric');
            app.Test_All_Speaker_Atten.Value = 45;
            app.Test_All_Speaker_Duration = uieditfield(app.UIFigure, 'numeric');
            app.Test_All_Speaker_Duration.Value = 0.5;
            app.Motu_Sample_Rate_Label_2 = uilabel(app.UIFigure);
            app.Motu_Sample_Rate_Label_2.Text = '(Separately)';
            app.Simultaneous_Test = uibutton(app.UIFigure, 'push');
            app.Simultaneous_Test.ButtonPushedFcn = createCallbackFcn(app, @Simultaneous_TestButtonPushed, true);
            app.Simultaneous_Test.Text = 'Test All Speakers';
            app.Motu_Sample_Rate_Label_3 = uilabel(app.UIFigure);
            app.Motu_Sample_Rate_Label_3.Text = '(Simultaneously)';
            app.Simultaneous_Test_Duration = uieditfield(app.UIFigure, 'numeric');
            app.Simultaneous_Test_Duration.Value = 0.5;
            app.Simultaneous_Test_Atten = uieditfield(app.UIFigure, 'numeric');
            app.Simultaneous_Test_Atten.Value = 45;
            app.WeightgEditFieldLabel = uilabel(app.UIFigure);
            app.WeightgEditFieldLabel.Text = 'Weight (g)';
            app.WeightEditField = uieditfield(app.UIFigure, 'numeric');
            app.WeightEditField.ValueChangedFcn = createCallbackFcn(app, @WeightEditFieldValueChanged, true);
            app.Weight_Directory = uilabel(app.UIFigure);
            app.Weight_Directory.Text = 'F:\Dropbox\Ferret_weights\Online';
            app.SquidEditionLabel = uilabel(app.UIFigure);
            app.SquidEditionLabel.Text = 'Squid Edition';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.TaskListBoxLabel = uilabel(app.UIFigure);
            app.TaskListBoxLabel.Text = 'Task';
            app.TaskListBox = uilistbox(app.UIFigure);
            app.TaskListBox.ValueChangedFcn = createCallbackFcn(app, @TaskListBoxValueChanged, true);
            app.ParametersListBoxLabel = uilabel(app.UIFigure);
            app.ParametersListBoxLabel.Text = 'Parameters';
            app.ParametersListBox = uilistbox(app.UIFigure);
            app.ParametersListBox.ValueChangedFcn = createCallbackFcn(app, @ParametersListBoxValueChanged, true);
            app.Session_Time = uilabel(app.UIFigure);
            app.Session_Time.Text = 'Session time';
            app.LevelsListBoxLabel = uilabel(app.UIFigure);
            app.LevelsListBoxLabel.Text = 'Levels';
            app.LevelsListBox = uilistbox(app.UIFigure);
            app.LevelsListBox.ValueChangedFcn = createCallbackFcn(app, @LevelsListBoxValueChanged, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SquidLayoutTab = uitab(app.TabGroup);
            app.SquidLayoutTab.Title = 'Squid Layout';
            app.Grid_Layout = uiaxes(app.SquidLayoutTab);
            app.AudioConnectionsTab = uitab(app.TabGroup);
            app.AudioConnectionsTab.Title = 'Audio Connections';
            app.Audio_Layout = uiaxes(app.AudioConnectionsTab);
            app.CurrentParametersTab = uitab(app.TabGroup);
            app.CurrentParametersTab.Title = 'Current Parameters';
            app.parameter_table = uitable(app.CurrentParametersTab);
            app.parameter_table.ColumnName = {'Field'; 'Value'};
            app.parameter_table.RowName = {};
            app.parameter_table.ColumnEditable = [false true];
            app.StimuliTab = uitab(app.TabGroup);
            app.StimuliTab.Title = 'Stimuli';
            app.chan_sequence = uiaxes(app.StimuliTab);
            title(app.chan_sequence, 'Clicks')
            xlabel(app.chan_sequence, 'Time')
            ylabel(app.chan_sequence, 'Channel')
            app.current_status = uilabel(app.UIFigure);
            app.current_status.Text = 'Current Status';
            app.Motu_Buffer_Size_Label = uilabel(app.UIFigure);
            app.Motu_Buffer_Size_Label.Text = 'Motu buffer size';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.PauseButton = uibutton(app.UIFigure, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.current_block = uilabel(app.UIFigure);
            app.current_block.Text = 'Current Block';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GoFerret_Squid

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end